classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel2     matlab.ui.container.Panel
        slider3      matlab.ui.control.Slider
        slider4      matlab.ui.control.Slider
        slider5      matlab.ui.control.Slider
        slider6      matlab.ui.control.Slider
        slider7      matlab.ui.control.Slider
        slider8      matlab.ui.control.Slider
        slider9      matlab.ui.control.Slider
        slider10     matlab.ui.control.Slider
        slider11     matlab.ui.control.Slider
        text4        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text8        matlab.ui.control.Label
        text9        matlab.ui.control.Label
        text10       matlab.ui.control.Label
        text11       matlab.ui.control.Label
        text12       matlab.ui.control.Label
        slider12     matlab.ui.control.Slider
        text13       matlab.ui.control.Label
        text16       matlab.ui.control.Label
        text19       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        text21       matlab.ui.control.Label
        text22       matlab.ui.control.Label
        text23       matlab.ui.control.Label
        text24       matlab.ui.control.Label
        text25       matlab.ui.control.Label
        text26       matlab.ui.control.Label
        text27       matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        pushbutton2  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        pushbutton5  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
        slider15     matlab.ui.control.Slider
        text14       matlab.ui.control.Label
        uipanel3     matlab.ui.container.Panel
        axes3        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        uipanel4     matlab.ui.container.Panel
        text3        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        text2        matlab.ui.control.Label
    end

    
    properties (Access = private)
        fullpathname; y; Fs; Volume; g1; g2; g3; g4; g5; g6; g7; g8; g9; g10; yT;
    end
    
    methods (Access = private)
        function edit1_CreateFcn(app, hObject)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))

                set(hObject,'BackgroundColor','white');
            end
        end
        
        function play_equalizer(app)
            global player;
            [app.y,app.Fs] = audioread(app.fullpathname);
            app.y=app.y(:,1);
            t=(0:length(app.y)-1)/app.Fs;
            app.Volume=get(app.slider15,'value');
            %app.y=app.y(NewStart:end,:);
            app.g1=get(app.slider3,'value');
            app.g2=get(app.slider4,'value');
            app.g3=get(app.slider5,'value');
            app.g4=get(app.slider7,'value');
            app.g5=get(app.slider8,'value');
            app.g6=get(app.slider9,'value');
            app.g7=get(app.slider10,'value');
            app.g8=get(app.slider11,'value');
            app.g9=get(app.slider6,'value');
            app.g10=get(app.slider12,'value');
            app.text16.Text=num2str(app.g1);
            app.text19.Text=num2str(app.g2);
            app.text20.Text=num2str(app.g3);
            app.text21.Text=num2str(app.g4);
            app.text22.Text=num2str(app.g5);
            app.text23.Text=num2str(app.g6);
            app.text24.Text=num2str(app.g7);
            app.text25.Text=num2str(app.g8);
            app.text26.Text=num2str(app.g9);
            app.text27.Text=num2str(app.g10);
           
            
            cut_off=200; %cut off low pass dalama Hz
            orde=400;
            a=fir1(orde,cut_off/(app.Fs/2),'low');
            y1=10^(app.g1/10)*myfilter(a,1,app.y);
            
            % Spectrum parameters setup
            window = hamming(128); 
            noverlap = 120; % Number of overlapping samples per segment
            nfft = 128; % Length of FFT transformation
            
            % %bandpass1
            f1=201;f2=400;
            b1=fir1(orde,[f1/(app.Fs/2) f2/(app.Fs/2)],'bandpass');
            y2=10^(app.g2/10)*myfilter(b1,1,app.y);
            %
            % %bandpass2
            f3=401;f4=800;
            b2=fir1(orde,[f3/(app.Fs/2) f4/(app.Fs/2)],'bandpass');
            y3=10^(app.g3/10)*myfilter(b2,1,app.y);
            %
            % %bandpass3
            f4=801;f5=1500;
            b3=fir1(orde,[f4/(app.Fs/2) f5/(app.Fs/2)],'bandpass');
            y4=10^(app.g4/10)*myfilter(b3,1,app.y);
            %
            % %bandpass4
            f5=1501;f6=3000;
            b4=fir1(orde,[f5/(app.Fs/2) f6/(app.Fs/2)],'bandpass');
            y5=10^(app.g5/10)*myfilter(b4,1,app.y);
            %
            % %bandpass5
            f7=3001;f8=5000;
            b5=fir1(orde,[f7/(app.Fs/2) f8/(app.Fs/2)],'bandpass');
            y6=10^(app.g6/10)*myfilter(b5,1,app.y);
            %
            % %bandpass6            
            f9=5001;f10=7000;
            b6=fir1(orde,[f9/(app.Fs/2) f10/(app.Fs/2)],'bandpass');
            y7=10^(app.g7/10)*myfilter(b6,1,app.y);
            %
            % %bandpass7
            f11=7001;f12=10000;
            b7=fir1(orde,[f11/(app.Fs/2) f12/(app.Fs/2)],'bandpass');
            y8=10^(app.g8/10)*myfilter(b7,1,app.y);
            %
            % %bandpass8
            f13=10001;f14=15000;
            b8=fir1(orde,[f13/(app.Fs/2) f14/(app.Fs/2)],'bandpass');
            y9=10^(app.g9/10)*myfilter(b8,1,app.y);
            %
            %highpass
            cut_off2=15000;
            c=fir1(orde,cut_off2/(app.Fs/2),'high');
            y10=10^(app.g10/10)*myfilter(c,1,app.y);
            app.yT=y1+y2+y3+y4+y5+y6+y7+y8+y9+y10;
            
            player = audioplayer(app.Volume*app.yT, app.Fs);

            % Plot origin Spectrum
            [S1,F1,T1]=spectrogram(app.y,window,noverlap,nfft,app.Fs,'yaxis');
            imagesc(app.axes2,T1,F1,log(1+abs(S1)));
            axis(app.axes2,'tight');view(app.axes2,0,270);
            app.axes2.XTickMode='auto';app.axes2.YTickMode='auto';
            %
            % Plot output Spectrum
            [S2,F2,T2]=spectrogram(app.yT,window,noverlap,nfft,app.Fs,'yaxis');
            imagesc(app.axes3,T2,F2,log(1+abs(S2)));
            axis(app.axes3,'tight');view(app.axes3,0,270);
            app.axes3.XTickMode='auto';app.axes3.YTickMode='auto';
             
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tes_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to tes (see VARARGIN)
%             
%             % Choose default command line output for tes
%             vol = 2.5;
%             set(app.slider15,'value',vol);
%             % Update handles structure
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uigetfile({'*.wav'},'File Selector');
            app.fullpathname = strcat(pathname, filename);
            app.text3.Text=app.fullpathname; %showing fullpathname
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            global player;
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %equalizer_play();
            play_equalizer(app);
            play(player);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global player;
            play_equalizer(app);
            pause(player);
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global player;
            play_equalizer(app);
            stop(player);
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global player;
            play_equalizer(app);
            resume(player);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Equaliser-Group 1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Equalizer';
            app.slider3 = uislider(app.uipanel2);
            app.slider3.Limits = [-12 12];
            app.slider3.MajorTicks = [];
            app.slider3.MinorTicks = [];
            app.slider4 = uislider(app.uipanel2);
            app.slider4.Limits = [-12 12];
            app.slider4.MajorTicks = [];
            app.slider4.MinorTicks = [];
            app.slider5 = uislider(app.uipanel2);
            app.slider5.Limits = [-12 12];
            app.slider5.MajorTicks = [];
            app.slider5.MinorTicks = [];
            app.slider6 = uislider(app.uipanel2);
            app.slider6.Limits = [-12 12];
            app.slider6.MajorTicks = [];
            app.slider6.MinorTicks = [];
            app.slider7 = uislider(app.uipanel2);
            app.slider7.Limits = [-12 12];
            app.slider7.MajorTicks = [];
            app.slider7.MinorTicks = [];
            app.slider8 = uislider(app.uipanel2);
            app.slider8.Limits = [-12 12];
            app.slider8.MajorTicks = [];
            app.slider8.MinorTicks = [];
            app.slider9 = uislider(app.uipanel2);
            app.slider9.Limits = [-12 12];
            app.slider9.MajorTicks = [];
            app.slider9.MinorTicks = [];
            app.slider10 = uislider(app.uipanel2);
            app.slider10.Limits = [-12 12];
            app.slider10.MajorTicks = [];
            app.slider10.MinorTicks = [];
            app.slider11 = uislider(app.uipanel2);
            app.slider11.Limits = [-12 12];
            app.slider11.MajorTicks = [];
            app.slider11.MinorTicks = [];
            app.text4 = uilabel(app.uipanel2);
            app.text4.Text = '0-200 Hz';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Text = '201-400 Hz';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = '401-800 Hz';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Text = '801-1.5 KHz';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = '1.5-3 KHz';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Text = '3-5 KHz';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = '5-7 KHz';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Text = '7-10 KHz';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Text = '10-15 KHz';
            app.slider12 = uislider(app.uipanel2);
            app.slider12.Limits = [-12 12];
            app.slider12.MajorTicks = [];
            app.slider12.MinorTicks = [];
            app.text13 = uilabel(app.uipanel2);
            app.text13.Text = '>15 KHz';
            app.text16 = uilabel(app.uipanel2);
            app.text16.Text = '';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Text = '';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Text = '';
            app.text21 = uilabel(app.uipanel2);
            app.text21.Text = '';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Text = '';
            app.text23 = uilabel(app.uipanel2);
            app.text23.Text = '';
            app.text24 = uilabel(app.uipanel2);
            app.text24.Text = '';
            app.text25 = uilabel(app.uipanel2);
            app.text25.Text = '';
            app.text26 = uilabel(app.uipanel2);
            app.text26.Text = '';
            app.text27 = uilabel(app.uipanel2);
            app.text27.Text = '';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Setting';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'Play';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'Pause';
            app.pushbutton5 = uibutton(app.uipanel1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Text = 'Resume';
            app.pushbutton4 = uibutton(app.uipanel1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Text = 'Stop';
            app.slider15 = uislider(app.uipanel1);
            app.slider15.Limits = [0 5];
            app.slider15.MajorTicks = [];
            app.slider15.MinorTicks = [];
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'Volume';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Axes';
            app.axes3 = uiaxes(app.uipanel3);
            title(app.axes3, 'Output spectrum')
            xlabel(app.axes3, 'Time (s)')
            ylabel(app.axes3, 'Frequency (kHz)')
            app.axes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes2 = uiaxes(app.uipanel3);
            title(app.axes2, 'Input spectrum')
            xlabel(app.axes2, 'Time (s)')
            ylabel(app.axes2, 'Frequency (kHz)')
            app.axes2.XTick = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.axes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.uipanel4 = uipanel(app.figure1);
            app.text3 = uilabel(app.uipanel4);
            app.text3.Text = '';
            app.pushbutton1 = uibutton(app.uipanel4, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Browse';
            app.text2 = uilabel(app.uipanel4);
            app.text2.Text = 'File Address';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @tes_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end